classdef DynamicSpeechApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DynamicSpeechAppFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Viewing                         matlab.ui.container.Tab
        View_images                     matlab.ui.container.Panel
        TimeframeSpinner                matlab.ui.control.Spinner
        TimeframeSpinnerLabel           matlab.ui.control.Label
        SaveAnimation                   matlab.ui.control.Button
        timingArea                      matlab.ui.control.TextArea
        overlaySeg                      matlab.ui.control.CheckBox
        crosshairs                      matlab.ui.control.CheckBox
        ImageContrastSlider             matlab.ui.control.Slider
        ImageContrastLabel              matlab.ui.control.Label
        AnimationSpeedfpsSlider         matlab.ui.control.Slider
        AnimationSpeedfpsSliderLabel    matlab.ui.control.Label
        StartStopAnimationButton        matlab.ui.control.StateButton
        seg3D                           matlab.ui.control.UIAxes
        Coronal                         matlab.ui.control.UIAxes
        Sagittal                        matlab.ui.control.UIAxes
        Axial                           matlab.ui.control.UIAxes
        DataLoading                     matlab.ui.container.Panel
        infoArea                        matlab.ui.control.TextArea
        SegmentationDirectoryEditField  matlab.ui.control.EditField
        SegmentationDirectoryEditFieldLabel  matlab.ui.control.Label
        DataDirectoryEditField          matlab.ui.control.EditField
        DataDirectoryEditFieldLabel     matlab.ui.control.Label
        LoadSegmentationsButton         matlab.ui.control.Button
        LoadImageDataButton             matlab.ui.control.Button
        Processing                      matlab.ui.container.Tab
        reset                           matlab.ui.control.Button
        RestartLabel                    matlab.ui.control.Label
        SaveResultsCallback             matlab.ui.control.Button
        SavingTitle                     matlab.ui.control.Label
    end


    properties (Access = public)
        appPath;        % the path for the app

        % current values for the GT figure/plot updates
        currentSl;
        currentFrame;
        currentX;
        currentY;

        % animation
        animate;                    % view the simulation

        IMG;                        % the full 4D image
        segmentations;              % the full 4D segmentations, loaded as stl files from Mimics
        isSegmentationLoaded = 0;   % are the segmentations loaded
        XLIM;
        YLIM;
        ZLIM;
        info;                       % image info struct
        directory;                  % image directory
        segDirectory;               % segmentation directory
        cycleLength;                % the cycle length, from the user in seconds
        timing;                     % the timing for each frame
    end

    properties (Access = private)
        Property15 % Description
    end

    methods (Access = private)

        function updateFigureAxes(app)

            contrast = app.ImageContrastSlider.Value;

            % sagittal
            aa = rot90(squeeze(app.IMG(:,:,app.currentSl,app.currentFrame)),-1);
            imshow(aa,[0 contrast],'Parent',app.Sagittal);

            % coronal
            aa = squeeze(app.IMG(app.info.imSize(1)-app.currentX,:,:,app.currentFrame));
            imshow(aa,[0 contrast],'Parent',app.Coronal);
            daspect(app.Coronal,[app.info.pixdim(1)/app.info.pixdim(3) 1 1]);

            % axial
            aa = squeeze(app.IMG(:,app.currentY,:,app.currentFrame));
            imshow(rot90(aa,-1),[0 contrast],'Parent',app.Axial);
            daspect(app.Axial,[app.info.pixdim(3)/app.info.pixdim(2) app.info.imSize(2)/app.info.imSize(1) 1]);

            if app.isSegmentationLoaded && app.overlaySeg.Value
                % sagittal
                hold(app.Sagittal,'on');
                img2 = rot90(app.segmentations(:,:,app.currentSl,app.currentFrame),-1);
                h = imagesc(app.Sagittal,cat(3, ones(size(img2)), ones(size(img2)), zeros(size(img2))));
                set(h,'AlphaData',0.15*img2)
                hold(app.Sagittal,'off');

                % coronal
                hold(app.Coronal,'on');
                img2 = squeeze(app.segmentations(app.info.imSize(1)-app.currentX,:,:,app.currentFrame));
                h = imagesc(app.Coronal,cat(3, ones(size(img2)), ones(size(img2)), zeros(size(img2))));
                set(h,'AlphaData',0.15*img2)
                hold(app.Coronal,'off');

                % axial
                hold(app.Axial,'on');
                img2 = rot90(squeeze(app.segmentations(:,app.currentY,:,app.currentFrame)),-1);
                h = imagesc(app.Axial,cat(3, ones(size(img2)), ones(size(img2)), zeros(size(img2))));
                set(h,'AlphaData',0.15*img2)
                hold(app.Axial,'off');
            end

            if app.crosshairs.Value
                % sagittal
                images.roi.Crosshair('Parent',app.Sagittal,...
                    'Position',[app.currentX app.currentY], ...
                    'LineWidth', 1, 'Color', 'r');
                % coronal
                images.roi.Crosshair('Parent',app.Coronal,...
                    'Position',[app.currentSl app.currentY ], ...
                    'LineWidth', 1, 'Color', 'r');
                % axial
                images.roi.Crosshair('Parent',app.Axial,...
                    'Position',[app.currentX app.info.imSize(3)-app.currentSl], ...
                    'LineWidth', 1, 'Color', 'r');
            end

            if app.isSegmentationLoaded
                cla(app.seg3D);
                hold(app.seg3D,'on');
                aa = smooth3(app.segmentations(:,:,:,app.currentFrame));
                hpatch = patch(app.seg3D, isosurface(aa,.5),'FaceColor','yellow','EdgeColor', 'none','FaceAlpha',0.75);
                reducepatch(hpatch,0.6);

                % Make it all look good
                camlight(app.seg3D);
                lighting(app.seg3D,'gouraud');
                lightangle(app.seg3D,0,0);
                view(app.seg3D, -90, -60);
                daspect(app.seg3D,app.info.pixdim)
                axis(app.seg3D,'off');
                hold(app.seg3D,'off');
            end

            % finally, add in information for timing
            app.timingArea.Value = sprintf('frame: %i\t\ttime: %1.1f ms',...
                app.currentFrame, app.timing(app.currentFrame));
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.appPath = erase(mfilename('fullpath'),'DynamicSpeechApp');
            addpath(genpath(app.appPath))

%             app.DynamicSpeechAppFigure.Icon = 'axial_contrast.png';
            app.StartStopAnimationButton.Icon = 'icons8-circled-play-80.png';
        end

        % Button pushed function: LoadImageDataButton
        function LoadImageDataButtonPushed(app, event)
            clc;
            % from load data
            [app.directory, app.info, app.IMG] = loadNII();

            % re-focus the figure
            figure(app.DynamicSpeechAppFigure);

            % display the directory
            app.DataDirectoryEditField.Value = app.directory(strfind(app.directory,'\recons\'):end);

            % we also want the timing of one cycle, grabbed from the user
            prompt = {'\fontsize{13} Enter cycle duration (in s)'};
            definput = {'2'};

            dlgtitle = 'Timing';
            dims = [1 50];
            opts.Interpreter = 'tex'; 
            answer = inputdlg(prompt,dlgtitle,dims,definput,opts);
            app.cycleLength = str2double(answer{1})*1000;
            app.timing = 0:app.cycleLength/app.info.nframes:(app.info.nframes-1)*app.cycleLength;

            % add to info area
            app.infoArea.Value = sprintf(['Info\nresolution (mm): %1.2f x %1.2f x %1.2f\n' ...
                'frames: %i\ntime resolution (ms): %2.1f'],app.info.pixdim(1),app.info.pixdim(2),app.info.pixdim(3),...
                app.info.nframes,app.cycleLength/app.info.nframes);

            % initial values
            app.currentFrame = 1;
            app.currentX = round(app.info.imSize(1)/2);
            app.currentY = round(app.info.imSize(2)/2);
            app.currentSl = round(app.info.imSize(3)/2);

            app.TimeframeSpinner.Limits = [1, app.info.nframes];

            updateFigureAxes(app);
            app.SaveAnimation.Enable =  'on';
        end

        % Button pushed function: LoadSegmentationsButton
        function LoadSegmentationsButtonPushed(app, event)
%             currDir = cd;
            cd(app.directory);

            [app.segDirectory, app.segmentations, outLims] = loadSegmentations(app.info);
            % set limits for 3D plot
            app.seg3D.XLim = outLims(1:2);
            app.seg3D.YLim = outLims(3:4);
            app.seg3D.ZLim = outLims(5:6);
            
            % display the directory
            app.SegmentationDirectoryEditField.Value = app.segDirectory(strfind(app.segDirectory,'\speechmri\'):end);

            app.isSegmentationLoaded = 1;
            updateFigureAxes(app);
            app.overlaySeg.Visible = 'on';
            app.seg3D.Visible = 'on';
        end

        % Window button down function: DynamicSpeechAppFigure
        function DynamicSpeechAppFigureWindowButtonDown(app, event)
            changeFlag = 0;
            % depending on current tab the button down looks for different click locations
            cTab = app.TabGroup.SelectedTab.Title;
            if strcmp(cTab, 'Viewing')
                if ~isempty(app.IMG)

                    % check if point in the axial axis
                    xx = round(app.Axial.CurrentPoint);
                    % check that point makes sense
                    x = xx(1); y = xx(3);
                    if x > 0 && x < app.info.imSize(1) && y > 0 && y < app.info.imSize(3)
                        app.currentX = x;
                        app.currentSl = app.info.imSize(3) - y;
                        changeFlag = 1;
                    end

                    % check if point in the coronal axis
                    xx = round(app.Coronal.CurrentPoint);
                    % check that point makes sense
                    x = xx(1); y = xx(3);
                    if x > 0 && x < app.info.imSize(3) && y > 0 && y < app.info.imSize(2)
                        app.currentY = y;
                        app.currentSl = x;
                        changeFlag = 1;
                    end

                    % check if point in the sagittal axis
                    xx = round(app.Sagittal.CurrentPoint);
                    % check that point makes sense
                    x = xx(1); y = xx(3);
                    if x > 0 && x < app.info.imSize(1) && y > 0 && y < app.info.imSize(2)
                        app.currentX = x;
                        app.currentY = y;
                        changeFlag = 1;
                    end

                    if changeFlag
                        % update axes
                        updateFigureAxes(app);
                    end
                end
            end
        end

        % Value changed function: StartStopAnimationButton
        function StartStopAnimationButtonValueChanged(app, event)
            app.animate = app.StartStopAnimationButton.Value;
            while (app.animate)
                % hide some functionality
                app.TimeframeSpinner.Enable = 'off';
                app.TimeframeSpinner.Visible = 'off';
                app.TimeframeSpinnerLabel.Visible = 'off';
                app.AnimationSpeedfpsSlider.Enable = 'off';
                % first get current phase
                if app.currentFrame == size(app.IMG,4)
                    app.currentFrame = 1;
                else
                    app.currentFrame = app.currentFrame+1;
                end
                updateFigureAxes(app);
                pause(1/app.AnimationSpeedfpsSlider.Value)
            end
            app.AnimationSpeedfpsSlider.Enable = 'on';
            app.TimeframeSpinner.Enable = 'on';
            app.TimeframeSpinner.Visible = 'on';
            app.TimeframeSpinnerLabel.Visible = 'on';
            app.TimeframeSpinner.Value = app.currentFrame;
        end

        % Callback function
        function StartStopAnimationButton_resultsValueChanged(app, event)
            while (app.StartStopAnimationButton_results.Value)
                app.AnimationSpeedfpsSlider_results.Enable = 'off';
                % first get current phase
                if app.currentFrame1 == size(app.IMG,4)
                    app.currentFrame_R = 1;
                    app.currentFrame1 = 1;
                else
                    [ ~, app.currentFrame_R ] = min(abs(app.timing_DCE - app.timing(app.currentFrame1)));
                    app.currentFrame1 = app.currentFrame1+1;
                end
                updateFigureAxes_results(app);
                updateTimeAxes_results(app);
                pause(1/app.AnimationSpeedfpsSlider_results.Value)
            end
            app.AnimationSpeedfpsSlider_results.Enable = 'on';
        end

        % Value changed function: ImageContrastSlider
        function ImageContrastSliderValueChanged(app, event)
            updateFigureAxes(app);
            %             updateTimeAxes(app);
        end

        % Callback function
        function ImageContrastSlider_resultsValueChanged(app, event)
            updateFigureAxes_results(app);
            updateTimeAxes_results(app);
        end

        % Button pushed function: SaveResultsCallback
        function SaveResultsCallbackButtonPushed(app, event)
            % grab specifics of simulation

            savePrefix = ['simulation_results_' datestr(now,30)] ;
            saveFolder = fullfile(app.appPath, 'results');
            if ~exist(saveFolder,'dir')
                mkdir(saveFolder);
            end
            saveName =  fullfile(saveFolder, savePrefix);

            % save the simulation information in a results.sim struct
            results = [];
            results.sim.B0          = app.B0_sim_dropdown.Value;
            results.sim.TR          = app.TR_sim.Value;
            results.sim.TE          = app.TE_sim.Value;
            results.sim.alpha       = app.alpha_sim.Value;
            results.sim.scanTime    = app.scanTime.Value;

            results.sim.matrixRL    = app.matrixRL;
            results.sim.matrixAP    = app.matrixAP;
            results.sim.matrixFH    = app.matrixFH;
            results.sim.resRL       = app.leftrightRes.Value;
            results.sim.resAP       = app.anteriorposteriorRes.Value;
            results.sim.resFH       = app.footheadRes.Value;

            results.sim.relaxivity  = app.relaxivity.Value;
            results.sim.injectionTime   = app.injectionTime.Value;
            results.sim.contrastSamplingTime = app.contrastSamplingTime.Value;

            if app.IncluderespiratorymotionCheckBox.Value
                results.sim.respPeriod = app.RespPeriod.Value;
            else
                results.sim.respPeriod = 'N/A';
            end

            % the ground-truth simulation
            results.images.GroundTruth.img = app.IMG;
            results.images.GroundTruth.timing = app.timing;

            % save the reconstruction information in a results.recon struct
            results.recon.trajectory = app.SamplingtrajectoryDropDown.Value;
            results.recon.DCEframelength    = app.DCE_frameLength.Value;
            results.recon.DCEframes         = app.nt;
            results.recon.DCE_R             = app.accelerationFactor.Text;

            results.recon.SNR                     = app.SNR;

            if app.MotioncorrectionCheckBox.Value
                results.recon.Resp = app.Resp;
            end

            % the final recon
            results.images.Recon.img = app.finalRecon;
            results.images.Recon.timing = app.timing_DCE;

            save([saveName '.mat'],'results','-v7.3')

            % inform of the saving
            msgbox(['results saved to ' saveName '.mat'], 'Saving complete')
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            delete(app.DynamicSpeechAppFigure);     % close the app
            DynamicSpeechApp;                       % re-open it
        end

        % Value changed function: crosshairs
        function crosshairsValueChanged(app, event)
            updateFigureAxes(app);
        end

        % Value changed function: TimeframeSpinner
        function TimeframeSpinnerValueChanged(app, event)
            if app.TimeframeSpinner.Value > app.info.nframes
                app.TimeframeSpinner.Value = 1;
            elseif app.TimeframeSpinner.Value == 0
                app.TimeframeSpinner.Value = app.info.nframes;
            end
            app.currentFrame = app.TimeframeSpinner.Value;
            updateFigureAxes(app);
        end

        % Value changed function: overlaySeg
        function overlaySegValueChanged(app, event)
            updateFigureAxes(app);
        end

        % Button pushed function: SaveAnimation
        function SaveAnimationButtonPushed(app, event)
            % temporarily hide other things for plotting
            app.SaveAnimation.Visible = 'off';
            app.StartStopAnimationButton.Visible = 'off';
            app.AnimationSpeedfpsSlider.Visible = 'off';
            app.AnimationSpeedfpsSliderLabel.Visible = 'off';
            app.ImageContrastLabel.Visible = 'off';
            app.ImageContrastSlider.Visible = 'off';
            app.TimeframeSpinner.Visible = 'off';
            app.TimeframeSpinnerLabel.Visible = 'off';
            app.overlaySeg.Visible = 'off';
            app.crosshairs.Visible = 'off';

            delay = 2*mean(diff(app.timing))/1000;

            [file,path] = uiputfile('*.gif','Selection file name and location');
            filename = fullfile(path,file);
            app.currentFrame = 1;
            updateFigureAxes(app);
            ff = getframe(app.DynamicSpeechAppFigure, [68 75 800 750]);

            % Turn screenshot into image
            im = frame2im(ff);

            % Turn image into indexed image (the gif format needs this)
            [imind,cm] = rgb2ind(im,256);
            imwrite(imind,cm,filename,'gif', 'WriteMode','overwrite','DelayTime', delay, 'LoopCount', Inf);

            % loop over time frames and record
            for t = 2:app.info.nframes
                app.currentFrame = t;
                updateFigureAxes(app);
                ff = getframe(app.DynamicSpeechAppFigure, [68 75 800 750]);

                % Turn screenshot into image
                im = frame2im(ff);

                % Turn image into indexed image (the gif format needs this)
                [imind,cm] = rgb2ind(im,256);
                imwrite(imind,cm, filename,'gif','WriteMode','append','DelayTime',delay);
            end

            % turn back on
            app.SaveAnimation.Visible = 'on';
            app.StartStopAnimationButton.Visible = 'on';
            app.AnimationSpeedfpsSlider.Visible = 'on';
            app.AnimationSpeedfpsSliderLabel.Visible = 'on';
            app.ImageContrastLabel.Visible = 'on';
            app.ImageContrastSlider.Visible = 'on';
            app.TimeframeSpinner.Visible = 'on';
            app.TimeframeSpinnerLabel.Visible = 'on';
            app.overlaySeg.Visible = 'on';
            app.crosshairs.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DynamicSpeechAppFigure = uifigure('Visible', 'off');
            app.DynamicSpeechAppFigure.Name = 'Dynamic Speech';
            app.DynamicSpeechAppFigure.WindowButtonDownFcn = createCallbackFcn(app, @DynamicSpeechAppFigureWindowButtonDown, true);
            app.TabGroup = uitabgroup(app.DynamicSpeechAppFigure);
            app.Viewing = uitab(app.TabGroup);
            app.Viewing.Title = 'Viewing';
            app.DataLoading = uipanel(app.Viewing);
            app.DataLoading.Title = 'Data Loading';
            app.LoadImageDataButton = uibutton(app.DataLoading, 'push');
            app.LoadImageDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageDataButtonPushed, true);
            app.LoadImageDataButton.Tooltip = {'load Philips 4D flow reconstructed par/rec data'};
            app.LoadImageDataButton.Text = 'Load Image Data';
            app.LoadSegmentationsButton = uibutton(app.DataLoading, 'push');
            app.LoadSegmentationsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSegmentationsButtonPushed, true);
            app.LoadSegmentationsButton.Tooltip = {'choose folder with segmentation dicoms (from nnUNET or Mimics)'};
            app.LoadSegmentationsButton.Text = 'Load Segmentations';
            app.DataDirectoryEditFieldLabel = uilabel(app.DataLoading);
            app.DataDirectoryEditFieldLabel.Text = 'Data Directory';
            app.DataDirectoryEditField = uieditfield(app.DataLoading, 'text');
            app.DataDirectoryEditField.Editable = 'off';
            app.SegmentationDirectoryEditFieldLabel = uilabel(app.DataLoading);
            app.SegmentationDirectoryEditFieldLabel.Text = 'Segmentation Directory';
            app.SegmentationDirectoryEditField = uieditfield(app.DataLoading, 'text');
            app.SegmentationDirectoryEditField.Editable = 'off';
            app.infoArea = uitextarea(app.DataLoading);
            app.View_images = uipanel(app.Viewing);
            app.View_images.Title = 'Image viewing';
            app.Axial = uiaxes(app.View_images);
            title(app.Axial, 'Axial')
            app.Axial.XTick = [];
            app.Axial.YTick = [];
            app.Sagittal = uiaxes(app.View_images);
            title(app.Sagittal, 'Sagittal')
            app.Sagittal.XTick = [];
            app.Sagittal.YTick = [];
            app.Coronal = uiaxes(app.View_images);
            title(app.Coronal, 'Coronal')
            app.Coronal.XTick = [];
            app.Coronal.YTick = [];
            app.seg3D = uiaxes(app.View_images);
            app.seg3D.XTick = [];
            app.seg3D.YTick = [];
            app.seg3D.Visible = 'off';
            app.StartStopAnimationButton = uibutton(app.View_images, 'state');
            app.StartStopAnimationButton.ValueChangedFcn = createCallbackFcn(app, @StartStopAnimationButtonValueChanged, true);
            app.StartStopAnimationButton.Text = 'Start/Stop Animation';
            app.AnimationSpeedfpsSliderLabel = uilabel(app.View_images);
            app.AnimationSpeedfpsSliderLabel.Text = 'Animation Speed (fps)';
            app.AnimationSpeedfpsSlider = uislider(app.View_images);
            app.AnimationSpeedfpsSlider.Limits = [0.5 8];
            app.AnimationSpeedfpsSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.AnimationSpeedfpsSlider.MinorTicks = [0 2 6 8 10 12 14 16 18 34];
            app.AnimationSpeedfpsSlider.Value = 2;
            app.ImageContrastLabel = uilabel(app.View_images);
            app.ImageContrastLabel.Text = 'Image Contrast';
            app.ImageContrastSlider = uislider(app.View_images);
            app.ImageContrastSlider.Limits = [0 1];
            app.ImageContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ImageContrastSliderValueChanged, true);
            app.ImageContrastSlider.MinorTicks = [];
            app.ImageContrastSlider.Value = 0.1;
            app.crosshairs = uicheckbox(app.View_images);
            app.crosshairs.ValueChangedFcn = createCallbackFcn(app, @crosshairsValueChanged, true);
            app.crosshairs.Tooltip = {''};
            app.crosshairs.Text = 'show crosshairs';
            app.overlaySeg = uicheckbox(app.View_images);
            app.overlaySeg.ValueChangedFcn = createCallbackFcn(app, @overlaySegValueChanged, true);
            app.overlaySeg.Visible = 'off';
            app.overlaySeg.Tooltip = {''};
            app.overlaySeg.Text = 'overlay segmentation';
            app.timingArea = uitextarea(app.View_images);
            app.SaveAnimation = uibutton(app.View_images, 'push');
            app.SaveAnimation.ButtonPushedFcn = createCallbackFcn(app, @SaveAnimationButtonPushed, true);
            app.SaveAnimation.Enable = 'off';
            app.SaveAnimation.Tooltip = {'save animation of plots over time'};
            app.SaveAnimation.Text = 'Save animation';
            app.TimeframeSpinnerLabel = uilabel(app.View_images);
            app.TimeframeSpinnerLabel.Text = 'Time frame';
            app.TimeframeSpinner = uispinner(app.View_images);
            app.TimeframeSpinner.ValueDisplayFormat = '%i';
            app.TimeframeSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeframeSpinnerValueChanged, true);
            app.TimeframeSpinner.Tooltip = {'shortcut ''↑'' or ''↓'''};
            app.TimeframeSpinner.Value = 1;
            app.Processing = uitab(app.TabGroup);
            app.Processing.Title = 'Results';
            app.SavingTitle = uilabel(app.Processing);
            app.SavingTitle.Text = 'Saving';
            app.SaveResultsCallback = uibutton(app.Processing, 'push');
            app.SaveResultsCallback.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsCallbackButtonPushed, true);
            app.SaveResultsCallback.Text = 'Save results';
            app.RestartLabel = uilabel(app.Processing);
            app.RestartLabel.Text = 'Restart';
            app.reset = uibutton(app.Processing, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = 'reset app';
            % Show the figure after all components are created
            app.DynamicSpeechAppFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DynamicSpeechApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DynamicSpeechAppFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DynamicSpeechAppFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DynamicSpeechAppFigure)
        end
    end
end